classdef SoundFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RestartAppButton            matlab.ui.control.Button
        FilterButton                matlab.ui.control.StateButton
        slider                      matlab.ui.control.Slider
        SelectCutOffFrequencyLabel  matlab.ui.control.Label
        statusField                 matlab.ui.control.EditField
        FrequencyResponseButton     matlab.ui.control.StateButton
        ClearButton                 matlab.ui.control.Button
        PlotButton                  matlab.ui.control.Button
        PlayButton                  matlab.ui.control.StateButton
        mainDrop                    matlab.ui.control.DropDown
        MixedSoundButton            matlab.ui.control.Button
        File2text                   matlab.ui.control.EditField
        FileNameEditFieldLabel_2    matlab.ui.control.Label
        File1text                   matlab.ui.control.EditField
        FileNameEditFieldLabel      matlab.ui.control.Label
        NormalSoundButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    properties (Access = private)
        File1Name % Description
        File2Name
        Signal1
        Fs1
        Signal2
        Fs2
        FilterSignal1
        FilterSignal2
        Fc
        player1
        player2
        soundplayfilter1
        soundplayfilter2
    end

    methods (Access = private)

        function  freqResponse(app)

            ax = app.UIAxes;


            freqbtn = app.FrequencyResponseButton.Value;

            switch app.mainDrop.Value

                case 'Normal Sound'


                    N = length(app.Signal1);
                    newSignal = fft(app.Signal1,N);
                    fr = [0:1/N:1-1/N]*app.Fs1-app.Fs1/2;
                    magSignal = abs(fftshift(newSignal));
                    phaseSignal = unwrap(angle(newSignal));


                    if(freqbtn == 0)
                        app.FrequencyResponseButton.Text = "Phase Spectrum";
                        plot(ax,fr,magSignal);
                        textarray = ['Frequency Spectrum Of ', app.File1text.Value];
                        ax.Title.String = textarray;
                        ax.XLabel.String = 'Frequency(Hz)';
                        ax.YLabel.String = 'Magnitude(dB)';
                        ax.FontWeight = 'bold';


                    else
                        app.FrequencyResponseButton.Text = "Frequency Spectrum";
                        plot(ax,fr,phaseSignal);
                        textarray = ['Phase Spectrum Of ', app.File1text.Value];
                        ax.Title.String = textarray;
                        ax.XLabel.String = 'Frequency(Hz)';
                        ax.YLabel.String = 'Phase(rad)';
                        ax.FontWeight = 'bold';

                    end


                case 'Mixed Sound'

                    N = length(app.Signal2);
                    newSignal = fft(app.Signal2,N);
                    fr = [0:1/N:1-1/N]*app.Fs2-app.Fs2/2;
                    magSignal = abs(fftshift(newSignal));
                    phaseSignal = unwrap(angle(newSignal));


                    if(freqbtn == 0)
                        app.FrequencyResponseButton.Text = "Phase Spectrum";
                        plot(ax,fr,magSignal);
                        textarray = ['Frequency Spectrum Of ', app.File2text.Value];
                        ax.Title.String = textarray;
                        ax.XLabel.String = 'Frequency(Hz)';
                        ax.YLabel.String = 'Magnitude(dB)';


                    else
                        app.FrequencyResponseButton.Text = "Frequency Spectrum";
                        plot(ax,fr,phaseSignal);
                        textarray = ['Phase Spectrum Of ', app.File2text.Value];
                        ax.Title.String = textarray;
                        ax.XLabel.String = 'Frequency(Hz)';
                        ax.YLabel.String = 'Phase(rad)';
                        ax.FontWeight = 'bold';


                    end

                case 'Filtered Sound-1'
                    N = length(app.Signal2);
                    fr = [0:1/N:1-1/N]*app.Fs2-app.Fs2/2;
                    phaseSignal = unwrap(angle(app.FilterSignal1));

                    if(freqbtn == 0)
                        app.FrequencyResponseButton.Text = "Phase Spectrum";
                        plot(ax,fr,app.FilterSignal1)
                        textarray = ['First Filtered ', app.File2text.Value, ' Signal Frequency Spectrum'];
                        ax.Title.String = textarray;
                        ax.XLabel.String = 'Frequency(Hz)';
                        ax.YLabel.String = 'Magnitude(dB)';


                    else
                        app.FrequencyResponseButton.Text = "Frequency Spectrum";
                        plot(ax,fr,phaseSignal);
                        textarray = ['First Filtered ', app.File2text.Value, ' Signal Phase Spectrum'];
                        ax.Title.String = textarray;
                        ax.XLabel.String = 'Frequency(Hz)';
                        ax.YLabel.String = 'Phase(rad)';
                        ax.FontWeight = 'bold';

                    end


                case 'Filtered Sound-2'

                    N = length(app.Signal2);
                    fr = [0:1/N:1-1/N]*app.Fs2-app.Fs2/2;
                    phaseSignal = unwrap(angle(app.FilterSignal2));

                    if(freqbtn == 0)
                        app.FrequencyResponseButton.Text = "Phase Spectrum";
                        plot(ax,fr,app.FilterSignal2)
                        textarray = ['Second Filtered ', app.File2text.Value, ' Signal Frequency Spectrum'];
                        ax.Title.String = textarray;
                        ax.XLabel.String = 'Frequency(Hz)';
                        ax.YLabel.String = 'Magnitude(dB)';


                    else
                        app.FrequencyResponseButton.Text = "Frequency Spectrum";
                        plot(ax,fr,phaseSignal);
                        textarray = ['Second Filtered ', app.File2text.Value, ' Signal Phase Spectrum'];
                        ax.Title.String = textarray;
                        ax.XLabel.String = 'Frequency(Hz)';
                        ax.YLabel.String = 'Phase(rad)';
                        ax.FontWeight = 'bold';

                    end


            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NormalSoundButton
        function NormalSoundButtonPushed(app, event)
            [file,path] = uigetfile;
            app.File1Name = [path file];
            app.File1text.Value = file;
            [app.Signal1, app.Fs1] = audioread(app.File1Name);
            app.player1 = audioplayer(app.Signal1,app.Fs1);

        end

        % Button pushed function: MixedSoundButton
        function MixedSoundButtonPushed(app, event)
            [file,path] = uigetfile;
            app.File2Name = [path file];
            app.File2text.Value = file;
            [app.Signal2, app.Fs2] = audioread(app.File2Name);
            app.player2 = audioplayer(app.Signal2,app.Fs2);
        end

        % Value changed function: PlayButton
        function PlayButtonValueChanged(app, event)
            value = app.PlayButton.Value;
            %dropdownvalue = app.mainDrop.Value;

            if(value)

                app.PlayButton.Text = "Stop";
                switch app.mainDrop.Value

                    case 'Normal Sound'

                        play(app.player1);
                        app.statusField.Value = "Playing Normal Sound !";

                    case 'Mixed Sound'

                        play(app.player2);
                        app.statusField.Value = "Playing Mixed Sound !";

                    case 'Filtered Sound-1'
                        sound(app.soundplayfilter1,app.Fs2)
                        app.statusField.Value = "Playing Filtered Sound-1 !";

                    case 'Filtered Sound-2'
                        sound(app.soundplayfilter2,app.Fs2)
                        app.statusField.Value = "Playing Filtered Sound-2 !";

                end


            else
                app.PlayButton.Text = "Play";

                switch app.mainDrop.Value

                    case 'Normal Sound'

                        stop(app.player1);
                        app.statusField.Value = "";

                    case 'Mixed Sound'

                        stop(app.player2);
                        app.statusField.Value = "";

                    case 'Filtered Sound-1'
                        clear sound
                        app.statusField.Value = "";

                    case 'Filtered Sound-2'
                        clear sound
                        app.statusField.Value = "";

                end


            end


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            freqResponse(app);
        end

        % Value changed function: FilterButton
        function FilterButtonValueChanged(app, event)
            value = app.FilterButton.Value;

            app.Fc = app.slider.Value;
            Ts = 1/app.Fs2; % Sampling period
            Fd = 2*pi*app.Fc;   %% Digital frequency
            Fwr = 2/Ts*tan(Fd*Ts/2); % pre-warped frequency
            % analog filter coefficients H(s) = 1/(1+s)
            num = [1]; % numerator coefficients
            den = [1 1]; % denominator coefficients

            % filter transformation from low pass to high pass
            [A1, B1] = lp2hp(num, den, Fwr);
            [a1, b1] = bilinear(A1, B1, app.Fs2); % Bilinear Transformation
            [A2, B2] = lp2lp(num, den, Fwr);
            [a2, b2] = bilinear(A2, B2, app.Fs2);

            filtersig1 = filter(a1,b1,app.Signal2);
            app.soundplayfilter1 = filtersig1;
            filtersig1 = fft(filtersig1,length(app.Signal2));
            filtersig1 = filtersig1 / app.Fs2 ;
            app.FilterSignal1 = abs(fftshift(filtersig1));


            filtersig2 = filter(a2,b2,app.Signal2);
            app.soundplayfilter2 = filtersig2;
            filtersig2 = fft(filtersig2,length(app.Signal2));
            filtersig2 = filtersig2 / app.Fs2 ;
            app.FilterSignal2 = abs(fftshift(filtersig2));


            app.statusField.Value = "Filtering Done !";


        end

        % Value changed function: slider
        function sliderValueChanged(app, event)
            value = app.slider.Value;

        end

        % Value changing function: slider
        function sliderValueChanging(app, event)
            changingValue = event.Value;
            arr = [num2str(changingValue), ' Hz'];
            app.statusField.Value = arr;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
            set(app.slider,'Value',0);
            app.File1text.Value = "";
            app.File2text.Value = "";
            app.FrequencyResponseButton.Value = 0;
            app.mainDrop.Value = 'Normal Sound';
            app.statusField.Value = "";
            clear sound;

        end

        % Button pushed function: RestartAppButton
        function RestartAppButtonPushed(app, event)
                        
    app.UIFigure.Visible = 'off';
   
    SoundFilter(); 
   
    close(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NormalSoundButton = uibutton(app.UIFigure, 'push');
            app.NormalSoundButton.ButtonPushedFcn = createCallbackFcn(app, @NormalSoundButtonPushed, true);
            app.NormalSoundButton.Text = 'Normal Sound';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.File1text = uieditfield(app.UIFigure, 'text');
            app.FileNameEditFieldLabel_2 = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel_2.Text = 'File Name';
            app.File2text = uieditfield(app.UIFigure, 'text');
            app.MixedSoundButton = uibutton(app.UIFigure, 'push');
            app.MixedSoundButton.ButtonPushedFcn = createCallbackFcn(app, @MixedSoundButtonPushed, true);
            app.MixedSoundButton.Text = 'Mixed Sound';
            app.mainDrop = uidropdown(app.UIFigure);
            app.mainDrop.Items = {'Normal Sound', 'Mixed Sound', 'Filtered Sound-1', 'Filtered Sound-2', '', ''};
            app.mainDrop.Value = 'Normal Sound';
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.PlayButton.Text = 'Play';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.FrequencyResponseButton = uibutton(app.UIFigure, 'state');
            app.FrequencyResponseButton.Text = 'Frequency Response';
            app.statusField = uieditfield(app.UIFigure, 'text');
            app.statusField.Placeholder = 'Status';
            app.SelectCutOffFrequencyLabel = uilabel(app.UIFigure);
            app.SelectCutOffFrequencyLabel.Text = 'Select Cut Off Frequency';
            app.slider = uislider(app.UIFigure);
            app.slider.Limits = [0 4000];
            app.slider.MajorTicks = [0 500 1000 1500 2000 2500 3000 3500 4000];
            app.slider.MajorTickLabels = {'0', '500', '1000', '1500', '2000', '2500', '3000', '3500', '4000'};
            app.slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.slider.ValueChangingFcn = createCallbackFcn(app, @sliderValueChanging, true);
            app.slider.MinorTicks = [0 50 100 150 200 250 300 350 400 450 500 550 600 650 700 750 800 850 900 950 1000 1050 1100 1150 1200 1250 1300 1350 1400 1450 1500 1550 1600 1650 1700 1750 1800 1850 1900 1950 2000 2050 2100 2150 2200 2250 2300 2350 2400 2450 2500 2550 2600 2650 2700 2750 2800 2850 2900 2950 3000 3050 3100 3150 3200 3250 3300 3350 3400 3450 3500 3550 3700 3750 3800 3850 3900 3950 4000 36003650];
            app.FilterButton = uibutton(app.UIFigure, 'state');
            app.FilterButton.ValueChangedFcn = createCallbackFcn(app, @FilterButtonValueChanged, true);
            app.FilterButton.Text = 'Filter';
            app.RestartAppButton = uibutton(app.UIFigure, 'push');
            app.RestartAppButton.ButtonPushedFcn = createCallbackFcn(app, @RestartAppButtonPushed, true);
            app.RestartAppButton.Text = 'Restart App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SoundFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end